classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        NoofObjectsPickedEditFieldLabel  matlab.ui.control.Label
        NoofObjectsPickedEditField  matlab.ui.control.NumericEditField
        ChooseShapeDropDownLabel    matlab.ui.control.Label
        ChooseShapeDropDown         matlab.ui.control.DropDown
        UIAxes                      matlab.ui.control.UIAxes
        ChooseColoursPanel          matlab.ui.container.Panel
        RedCheckBox                 matlab.ui.control.CheckBox
        BlueCheckBox                matlab.ui.control.CheckBox
        YellowCheckBox              matlab.ui.control.CheckBox
        ImageProcessingSwitchLabel  matlab.ui.control.Label
        ImageProcessingSwitch       matlab.ui.control.Switch
    end

    properties (Access = private)
        MemoryTimer        % Timer object
        count = 0;
    end

    methods (Access = private)
    
       function MemoryTimerFcn(app,~,~)
            app.count = app.count + 1;
            fprintf("Second: %d\n", app.count);
            ImageProcessingSwitchValueChanged(app);
       end        
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %clear all;
            %close all;
            global imSub pcSub posPub posSub
            global red blue yellow rectangle cylinder triangle
            global ax
            global positions pixel_positions msg_counter
            global ipaddress
            ipaddress = '192.168.0.234';
            robotType = 'Gazebo';
            rosshutdown;
            rosinit(ipaddress);
            blockposes = rossubscriber('/gazebo/link_states');
            pause(2);
            posdata = receive(blockposes,10);
            imSub = rossubscriber('/camera/color/image_raw');
            pcSub = rossubscriber('/camera/depth/image_raw');
            infoSub = rossubscriber('/camera/color/camera_info'); 
            posPub = rospublisher('/cv_pos','std_msgs/String');
            posSub = rossubscriber('/cv_pos');
            
            rostopic list;
            
            % id, type, x, y, z
            positions = [{0, 'red', 0, 0, 0.6},
                            {1, 'blue', 0, 0.1, 0.6},
                            {2, 'yellow', 0.1, 0, 0.6}];
            
            % pixel positions are in x,y format
            pixel_positions = [167 87; 200 200; 400 400;];
                        
            msg_counter = 6;

            % Initially find all shapes/colours
            red = 1;
            blue = 2;
            yellow = 3;
            rectangle = 1;
            cylinder = 1;
            triangle = 1;
            
            % initialise plot
            ax = app.UIAxes;
            
             % Create timer object
            app.MemoryTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 0.5, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.MemoryTimerFcn);    % Specify callback function
            start(app.MemoryTimer);
        end

        % Value changed function: RedCheckBox
        function RedCheckBoxValueChanged(app, event)
            global red
            value = app.RedCheckBox.Value;
            red = value;
            disp(value);
        end

        % Value changed function: BlueCheckBox
        function BlueCheckBoxValueChanged(app, event)
            global blue
            value = app.BlueCheckBox.Value;
            if value == 1
                blue = 2;
            else
                blue = 0;
            end
            disp(value);
        end

        % Value changed function: YellowCheckBox
        function YellowCheckBoxValueChanged(app, event)
            global yellow
            value = app.YellowCheckBox.Value;
            if value == 1
                yellow = 3;
            else
                yellow = 0;
            end
            disp(value);
        end

        % Value changed function: ImageProcessingSwitch
        function ImageProcessingSwitchValueChanged(app, event)
            global imSub pcSub posPub posSub
            global red yellow blue
            global ax
            global positions pixel_positions msg_counter
            
            value = app.ImageProcessingSwitch.Value;
            if value == "Off"
                cla(ax);
            end
            if value == "On"
                 %while true
                xPos = 0;
                yPos = 0;
                zPos = 0;
                %figure(1)
                testIm  = readImage(imSub.LatestMessage);
                %imshow(testIm);
                
                %figure(2);
                depthIm  = readImage(pcSub.LatestMessage);
                % get location
                [xRed,yRed,zRed] = getPosColour(testIm, depthIm, red);
                [xBlue,yBlue,zBlue] = getPosColour(testIm, depthIm, blue);
                [xYellow,yYellow,zYellow] = getPosColour(testIm, depthIm, yellow);
                % combine
                xPos = cat(1,xRed,xBlue,xYellow);
                yPos = cat(1,yRed,yBlue,yYellow);
                %zPos = cat(1,zRed,zBlue,zYellow);
                imshow(testIm,'Parent',app.UIAxes);
                hold (app.UIAxes,'on');
                plot(app.UIAxes,xPos,yPos,'w*');
                
                
                %axis on;
                depth_max = max(max(depthIm));
                depth_min = min(min(depthIm));
                depthImDisplay = (depthIm-depth_min)/(depth_max - depth_min);
                % for grayscale image, matlab expects values from 0.0 to 1.0, not 0 to
                % 255
                %imshow(depthImDisplay);
                
                for i = 1:length(pixel_positions)
                    x = pixel_positions(i,1);
                    y = pixel_positions(i,2);
                    p3d = toCamera(x, y, depthIm(y,x)); % image reference in row, col format (ie y, x)
                    gp3d = toGlobal(p3d);
                end
                
                cv_msg = rosmessage(posPub);
                cv_msg.Data = sprintf("%d|%s",msg_counter,toString(positions));
                send(posPub,cv_msg);
                
                %saveIms("img"+msg_counter, testIm, depthIm, depthImDisplay)
                
                msg_counter = msg_counter + 1;
                %pause(10)
            %     msg = receive(posSub,3)
                
              
            %end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NoofObjectsPickedEditFieldLabel = uilabel(app.UIFigure);
            app.NoofObjectsPickedEditFieldLabel.Text = 'No. of Objects Picked';
            app.NoofObjectsPickedEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChooseShapeDropDownLabel = uilabel(app.UIFigure);
            app.ChooseShapeDropDownLabel.Text = 'Choose Shape';
            app.ChooseShapeDropDown = uidropdown(app.UIFigure);
            app.ChooseShapeDropDown.Items = {'Rectangular Prism', 'Cylinder', 'Triangular Prism', ''};
            app.ChooseShapeDropDown.Value = 'Rectangular Prism';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image Processing')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [0 640];
            app.UIAxes.YLim = [0 480];
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XTick = [0 640];
            app.UIAxes.XTickLabel = {'0'; '640'};
            app.ChooseColoursPanel = uipanel(app.UIFigure);
            app.ChooseColoursPanel.Title = 'Choose Colours';
            app.RedCheckBox = uicheckbox(app.ChooseColoursPanel);
            app.RedCheckBox.ValueChangedFcn = createCallbackFcn(app, @RedCheckBoxValueChanged, true);
            app.RedCheckBox.Text = 'Red';
            app.RedCheckBox.Value = true;
            app.BlueCheckBox = uicheckbox(app.ChooseColoursPanel);
            app.BlueCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlueCheckBoxValueChanged, true);
            app.BlueCheckBox.Text = 'Blue';
            app.BlueCheckBox.Value = true;
            app.YellowCheckBox = uicheckbox(app.ChooseColoursPanel);
            app.YellowCheckBox.ValueChangedFcn = createCallbackFcn(app, @YellowCheckBoxValueChanged, true);
            app.YellowCheckBox.Text = 'Yellow';
            app.YellowCheckBox.Value = true;
            app.ImageProcessingSwitchLabel = uilabel(app.UIFigure);
            app.ImageProcessingSwitchLabel.Text = 'Image Processing';
            app.ImageProcessingSwitch = uiswitch(app.UIFigure, 'slider');
            app.ImageProcessingSwitch.ValueChangedFcn = createCallbackFcn(app, @ImageProcessingSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end